classdef GUIforFEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        USEROUTPUTSPanel                matlab.ui.container.Panel
        SelectBoundaryConditionsButton  matlab.ui.control.Button
        ExportResultsButton             matlab.ui.control.Button
        RestoreSaveButton               matlab.ui.control.Button
        RESULTSPanel                    matlab.ui.container.Panel
        VonMisesMinLabel                matlab.ui.control.Label
        VonMisesMaxLabel                matlab.ui.control.Label
        GxyMinLabel                     matlab.ui.control.Label
        GxyMaxLabel                     matlab.ui.control.Label
        EyMinLabel                      matlab.ui.control.Label
        EyMaxLabel                      matlab.ui.control.Label
        ExMinLabel                      matlab.ui.control.Label
        ExMaxLabel                      matlab.ui.control.Label
        SxyMinLabel                     matlab.ui.control.Label
        SxyMaxLabel                     matlab.ui.control.Label
        SyMinLabel                      matlab.ui.control.Label
        SyMaxLabel                      matlab.ui.control.Label
        SxMinLabel                      matlab.ui.control.Label
        SxMaxLabel                      matlab.ui.control.Label
        VonMisesLabel                   matlab.ui.control.Label
        GxyStrainLabel                  matlab.ui.control.Label
        EyStrainLabel                   matlab.ui.control.Label
        ExStrainLabel                   matlab.ui.control.Label
        SxyStressLabel                  matlab.ui.control.Label
        SyStressLabel                   matlab.ui.control.Label
        SxStressLabel                   matlab.ui.control.Label
        TypeLabel                       matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        USERINPUTSPanel                 matlab.ui.container.Panel
        MeshSlider                      matlab.ui.control.Slider
        MeshSliderLabel                 matlab.ui.control.Label
        ResettoDefaultsButton           matlab.ui.control.Button
        ResultDropDown                  matlab.ui.control.DropDown
        ResultTypeDropDownLabel         matlab.ui.control.Label
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        YoungsModulusEditField          matlab.ui.control.NumericEditField
        YoungsModulusEditFieldLabel     matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        CuttingForceEditField           matlab.ui.control.NumericEditField
        CuttingForceEditFieldLabel      matlab.ui.control.Label
        ThrustForceEditField            matlab.ui.control.NumericEditField
        ThrustForceEditFieldLabel       matlab.ui.control.Label
        FEACONTROLSPanel                matlab.ui.container.Panel
        StatusLabel_2                   matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        ShowMeshInfoButton              matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        GenerateMeshButton              matlab.ui.control.Button
        LoadToolpathInputsButton        matlab.ui.control.Button
        ShowResultsButton               matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)

        n_element
        n_nodes
        E
        ncon
        X
        Y
        NDU
        dzero
        F
        v
        t
        K
        U
        Sx
        Sy
        Sxy
        Ex
        Ey
        Gxy
        Fc
        Ft

        geometryMode   % 'excel' or 'manual'
        selectedNodes  % for BC selection
        meshDensity    % scalar

        inputs % for saving input data

    end
    
    properties (Access = private)
        resultSummary % Results summary of stress' and strains'
    end

    methods (Access = private)

        function ResultDropDownValueChanged(app)

            switch app.ResultDropDown.Value

                case 'Deformation'
                    plot_deformed(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.U, app.F, app.dzero);

                case 'Sx Stress'
                    plot_contour(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.Sx, 'Sx Stress');

                case 'Sy Stress'
                    plot_contour(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.Sy, 'Sy Stress');

                case 'Sxy Stress'
                    plot_contour(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.Sxy, 'Sxy Stress');
                case 'Ex Strain'
                    plot_contour(app.UIAxes_2,...
                        app.ncon, app.X, app.Y, app.Ex, 'Ex Strain');
                case 'Ey Strain'
                     plot_contour(app.UIAxes_2,...
                        app.ncon, app.X, app.Y, app.Ey, 'Ey Strain');
                case 'Gxy Strain'
                    plot_contour(app.UIAxes_2, app.ncon, app.X, app.Y, app.Gxy, 'Gxy Strain');

                case 'Total Displacement'
                    Ux = app.U(1:2:end);
                    Uy = app.U(2:2:end);
                    Umag = sqrt(Ux.^2 + Uy.^2);
                    plot_contour(app.UIAxes_2, app.ncon, app.X, app.Y, Umag, '|U|');
                
                case 'Von Mises'
                    vm = sqrt(app.Sx.^2 + app.Sy.^2 - app.Sx.*app.Sy + 3*app.Sxy.^2);
                    plot_contour(app.UIAxes_2, app.ncon, app.X, app.Y, vm, 'Von Mises Stress');
            end

        end

        function GenerateMesh(app)

            if strcmp(app.GeometryDropDown.Value,'Manual')

                if isempty(app.manualX)
                    error('No manual geometry defined');
                end

                tri = delaunay(app.manualX, app.manualY);

                app.ncon = tri;
                app.X = app.manualX;
                app.Y = app.manualY;

                app.n_nodes = length(app.X);
                app.n_element = size(tri,1);

            end
        end
        function updateResultsBar(app, data, labelName)

            maxVal = max(data);
            minVal = min(data);

            % Store values
            app.resultSummary.(labelName).max = maxVal;
            app.resultSummary.(labelName).min = minVal;

            % Make labels visible when data is available
            app.SxMaxLabel.Visible = 'on';
            app.SxMinLabel.Visible = 'on';
            app.SyMaxLabel.Visible = 'on';
            app.SyMinLabel.Visible = 'on'; 
            app.SxyMaxLabel.Visible = 'on';
            app.SxyMinLabel.Visible = 'on';
            app.ExMaxLabel.Visible = 'on';
            app.ExMinLabel.Visible = 'on';
            app.EyMaxLabel.Visible = 'on';
            app.EyMinLabel.Visible = 'on';
            app.GxyMaxLabel.Visible = 'on';
            app.GxyMinLabel.Visible = 'on';
            app.VonMisesMaxLabel.Visible = 'on';
            app.VonMisesMinLabel.Visible = 'on';

            switch labelName
                case 'Sx'
                    app.SxMaxLabel.Text = sprintf('%.3e', maxVal);
                    app.SxMinLabel.Text = sprintf('%.3e', minVal);

                case 'Sy'
                    app.SyMaxLabel.Text = sprintf('%.3e', maxVal);
                    app.SyMinLabel.Text = sprintf('%.3e', minVal);

                case 'Sxy'
                    app.SxyMaxLabel.Text = sprintf('%.3e', maxVal);
                    app.SxyMinLabel.Text = sprintf('%.3e', minVal);

                case 'Ex'
                    app.ExMaxLabel.Text = sprintf('%.3e', maxVal);
                    app.ExMinLabel.Text = sprintf('%.3e', minVal);

                case 'Ey'
                    app.EyMaxLabel.Text = sprintf('%.3e', maxVal);
                    app.EyMinLabel.Text = sprintf('%.3e', minVal);

                case 'Gxy'
                    app.GxyMaxLabel.Text = sprintf('%.3e', maxVal);
                    app.GxyMinLabel.Text = sprintf('%.3e', minVal);

                case 'VonMises'
                    app.VonMisesMaxLabel.Text = sprintf('%.3e', maxVal);
                    app.VonMisesMinLabel.Text = sprintf('%.3e', minVal);
            end
        end
        function initialiseResults(app)

            app.resultSummary = struct();

            app.SxMaxLabel.Text = '-'; app.SxMinLabel.Text = '-';
            app.SyMaxLabel.Text = '-'; app.SyMinLabel.Text = '-';
            app.SxyMaxLabel.Text = '-'; app.SxyMinLabel.Text = '-';
            app.ExMaxLabel.Text = '-'; app.ExMinLabel.Text = '-';
            app.EyMaxLabel.Text = '-'; app.EyMinLabel.Text = '-';
            app.GxyMaxLabel.Text = '-'; app.GxyMinLabel.Text = '-';
            app.VonMisesMaxLabel.Text = '-'; app.VonMisesMinLabel.Text = '-';

        end

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)

            app.geometryMode = 'excel';
            app.meshDensity = app.MeshSlider.Value;
            app.selectedNodes = [];

           % app.GeometryDropDown.Items = {'Excel','Manual'};
           % app.GeometryDropDown.Value = 'Excel';

            app.ResultDropDown.Items = ...
                {'Deformation','Sx Stress','Sy Stress','Sxy Stress',...
                'Ex Strain', 'Ey Strain', 'Gxy Strain',... 
                'Total Displacement', 'Von Mises'};
            
            app.ResultDropDown.Value = 'Deformation';
            %load input values
            filename = 'app_inputs.mat';
            if isfile(filename)
                % Load the file into a temporary struct
                loadedStruct = load(filename);
                
                % Assign it back to your app property
                app.inputs = loadedStruct;
                
                % Update the GUI fields so the user sees the saved data
                app.Fc = app.inputs.Fc;
                app.Ft = app.inputs.Ft;
                app.E  = app.inputs.E;
                app.v  = app.inputs.v;
                app.t  = app.inputs.t;
                app.CuttingForceEditField.Value = app.Fc;
                app.ThrustForceEditField.Value = app.Ft;
                app.YoungsModulusEditField.Value = app.E;
                app.PoissonsRatioEditField.Value = app.v;
                app.ThicknessEditField.Value = app.t;
            end
            
            % Hide all result labels initially
            app.SxMaxLabel.Visible = 'off';
            app.SxMinLabel.Visible = 'off';
            app.SyMaxLabel.Visible = 'off';
            app.SyMinLabel.Visible = 'off';
            app.SxyMaxLabel.Visible = 'off';
            app.SxyMinLabel.Visible = 'off';
            app.ExMaxLabel.Visible = 'off';
            app.ExMinLabel.Visible = 'off';
            app.EyMaxLabel.Visible = 'off';
            app.EyMinLabel.Visible = 'off';
            app.GxyMaxLabel.Visible = 'off';
            app.GxyMinLabel.Visible = 'off';
            app.VonMisesMaxLabel.Visible = 'off';
            app.VonMisesMinLabel.Visible = 'off';
        end

        % Button pushed function: RestoreSaveButton
        function RestoreSaveButtonPushed(app, event)
            %load input values
            filename = 'app_inputs.mat';
            if isfile(filename)
                % Load the file into a temporary struct
                loadedStruct = load(filename);

                % Assign it back to your app property
                app.inputs = loadedStruct;

                % Update the GUI fields so the user sees the saved data
                app.Fc = app.inputs.Fc;
                app.Ft = app.inputs.Ft;
                app.E  = app.inputs.E;
                app.v  = app.inputs.v;
                app.t  = app.inputs.t;
                app.CuttingForceEditField.Value = app.Fc;
                app.ThrustForceEditField.Value = app.Ft;
                app.YoungsModulusEditField.Value = app.E;
                app.PoissonsRatioEditField.Value = app.v;
                app.ThicknessEditField.Value = app.t;
            end
        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)

            cla(app.UIAxes)

            patch(app.UIAxes,'Faces',app.ncon,'Vertices',[app.X app.Y], ...
                'FaceColor','none','EdgeColor','k');

            axis(app.UIAxes,'equal')
            grid(app.UIAxes,'on')
            title(app.UIAxes,'Generated Mesh')
            app.StatusLabel.Text = 'Generating mesh...';
            drawnow;

      
        end

        % Button pushed function: ShowMeshInfoButton
        function ShowMeshInfoButtonPushed(app, event)

            if isempty(app.ncon)
                uialert(app.UIFigure,'Load or create a model first.','No Model');
                return;
            end

            msg = sprintf([ ...
                'Nodes: %d\n' ...
                'Elements: %d\n' ...
                'DOFs: %d\n' ...
                'Young''s Modulus: %.3e Pa\n' ...
                'Poisson Ratio: %.2f'], ...
                app.n_nodes, ...
                app.n_element, ...
                2*app.n_nodes, ...
                app.E, ...
                app.v);

            uialert(app.UIFigure,msg,'Mesh Information');

        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)

            if isempty(app.ncon)
                uialert(app.UIFigure,'Load or create a model first.','No Model');
                return;
            end

            app.StatusLabel.Text = 'Solving...';
            drawnow;

            [app.U, app.Sx, app.Sy, app.Sxy, app.Ex, app.Ey, app.Gxy] = run_FEA( ...
                app.ncon, app.X, app.Y, ...
                app.E, app.v, app.t, ...
                app.F, app.dzero, app.NDU);

            uialert(app.UIFigure,'Analysis Complete','Solved');
            msg = sprintf('Max U = %.3e m\nMax Sx = %.3e Pa', ...
                max(abs(app.U)), max(abs(app.Sx)));
            uialert(app.UIFigure,msg,'Analysis Complete');

            ResultDropDownValueChanged(app)

            app.StatusLabel.Text = 'Complete';

            updateResultsBar(app, app.Sx, 'Sx');
            updateResultsBar(app, app.Sy, 'Sy');
            updateResultsBar(app, app.Sxy, 'Sxy');
            updateResultsBar(app, app.Ex, 'Ex');
            updateResultsBar(app, app.Ey, 'Ey');
            updateResultsBar(app, app.Gxy, 'Gxy');

            vm = sqrt(app.Sx.^2 + app.Sy.^2 - app.Sx.*app.Sy + 3*app.Sxy.^2);
            updateResultsBar(app, vm, 'VonMises');

        end

        % Callback function
        function DisplayDeformedStructureButtonPushed(app, event)

            plot_deformed(app.UIAxes_2, ...
                app.ncon, app.X, app.Y, ...
                app.U, app.F, app.dzero);

        end

        % Button pushed function: ShowResultsButton
        function ShowResultsButtonPushed(app, event)

                ResultDropDownValueChanged(app);

        end

        % Callback function
        function DrawGeometryButtonPushed(app, event)
            cla(app.UIAxes);
            hold(app.UIAxes,'on');

            title(app.UIAxes,'Click nodes. Press Enter when done');

            [x,y] = ginput;

            app.manualX = x;
            app.manualY = y;

            % Generate mesh immediately
            tri = delaunay(x,y);

            app.ncon = tri;
            app.X = x;
            app.Y = y;
            app.n_nodes = length(x);
            app.n_element = size(tri,1);

            % Plot mesh
            triplot(tri,x,y,'k','Parent',app.UIAxes);
            plot(app.UIAxes,x,y,'ro');

            %triangulate with delaunay(x,y)
            %assign to app.ncon

        end

        % Button pushed function: SelectBoundaryConditionsButton
        function SelectBoundaryConditionsButtonPushed(app, event)
            title(app.UIAxes,'Click nodes to fix');

            [x_click,y_click] = ginput;

            selected = [];

            for k = 1:length(x_click)
                dist = sqrt((app.X - x_click(k)).^2 + (app.Y - y_click(k)).^2);
                [~, idx] = min(dist);
                selected(end+1) = idx;
            end

            app.selectedNodes = unique(selected);

            % Visual feedback
            scatter(app.UIAxes, app.X(app.selectedNodes), ...
                app.Y(app.selectedNodes), 80, 'filled','r');

        end

        % Value changed function: ResultDropDown
        function ResultDropDownValueChanged2(app, event)
            if ~isempty(app.U)
                ResultDropDownValueChanged(app);
            end
        end

        % Value changed function: YoungsModulusEditField
        function YoungsModulusEditFieldValueChanged(app, event)
            app.E = app.YoungsModulusEditField.Value;

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            %export_data('FEA_Results.xlsx', app);

            app.inputs.Fc = app.CuttingForceEditField.Value;
            app.inputs.Ft = app.ThrustForceEditField.Value;
            app.inputs.E = app.YoungsModulusEditField.Value;
            app.inputs.v = app.PoissonsRatioEditField.Value;
            app.inputs.t = app.ThicknessEditField.Value;
        
            % 2. Save to a .mat file
            dataToSave = app.inputs;
            save('app_inputs.mat', '-struct', 'dataToSave', '-mat');
                        
            uiconfirm(app.UIFigure, 'inputs saved successfully!', 'Success');
            app.StatusLabel.Text = 'Export Data Success';
        end

        % Button pushed function: ResettoDefaultsButton
        function ResettoDefaultsButtonPushed(app, event)
            % Clear plots
            cla(app.UIAxes);
            cla(app.UIAxes_2);

            % Reset geometry
            app.ncon = [];
            app.X = [];
            app.Y = [];
            app.n_nodes = [];
            app.n_element = [];

            % Reset results
            app.U = [];
            app.Sx = [];
            app.Sy = [];
            app.Sxy = [];
            app.Ex = [];
            app.Ey = [];
            app.Gxy = [];

            % Reset BCs
            app.selectedNodes = [];

            % Reset dropdown
            app.ResultDropDown.Value = 'Deformation';

            % Update status
            app.StatusLabel.Text = 'Reset Complete';

        end

        % Button pushed function: LoadToolpathInputsButton
        function LoadToolpathInputsButtonPushed(app, event)
            
    % Read current material inputs from GUI fields
    app.Fc = app.CuttingForceEditField.Value;
    app.Ft = app.ThrustForceEditField.Value;
    app.E  = app.YoungsModulusEditField.Value;
    app.v  = app.PoissonsRatioEditField.Value;
    app.t  = app.ThicknessEditField.Value;

    % Launch custom geometry dialog
    [base_nodes, cutting_nodes, Fc, Ft, fixed_nodes] = custom_geometry();

    % Override force values with whatever came back from dialog
    app.Fc = Fc;
    app.Ft = Ft;

    % Build tool data with user-defined geometry and forces
    [app.ncon, app.X, app.Y, app.F, app.dzero, ...
        app.n_nodes, app.n_element, ...
        app.E, app.v, app.t, app.NDU] = ...
        build_tool_data(base_nodes, cutting_nodes, Fc, Ft, fixed_nodes);

    % Sync GUI fields with values used
    app.CuttingForceEditField.Value  = app.Fc;
    app.ThrustForceEditField.Value   = app.Ft;
    app.YoungsModulusEditField.Value = app.E;
    app.PoissonsRatioEditField.Value = app.v;
    app.ThicknessEditField.Value     = app.t;

    uialert(app.UIFigure, 'Data loaded successfully', 'Success');
    app.StatusLabel.Text = 'Toolpath Loaded Successfully';

        end

        % Value changed function: CuttingForceEditField
        function CuttingForceEditFieldValueChanged(app, event)
            app.Fc = app.CuttingForceEditField.Value;
            
        end

        % Value changed function: ThrustForceEditField
        function ThrustForceEditFieldValueChanged(app, event)
            app.Ft = app.ThrustForceEditField.Value;
            
        end

        % Value changed function: PoissonsRatioEditField
        function PoissonsRatioEditFieldValueChanged(app, event)
            app.v = app.PoissonsRatioEditField.Value;
            
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            app.t = app.ThicknessEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tool Geometry & Mesh Generation')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Contour Plot')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.FEACONTROLSPanel = uipanel(app.UIFigure);
            app.FEACONTROLSPanel.Title = 'FEA CONTROLS';
            app.ShowResultsButton = uibutton(app.FEACONTROLSPanel, 'push');
            app.ShowResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultsButtonPushed, true);
            app.ShowResultsButton.Text = 'Show Results';
            app.LoadToolpathInputsButton = uibutton(app.FEACONTROLSPanel, 'push');
            app.LoadToolpathInputsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadToolpathInputsButtonPushed, true);
            app.LoadToolpathInputsButton.Text = 'Load Toolpath & Inputs';
            app.GenerateMeshButton = uibutton(app.FEACONTROLSPanel, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.RunAnalysisButton = uibutton(app.FEACONTROLSPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ShowMeshInfoButton = uibutton(app.FEACONTROLSPanel, 'push');
            app.ShowMeshInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshInfoButtonPushed, true);
            app.ShowMeshInfoButton.Text = 'Show Mesh Info';
            app.StatusLabel = uilabel(app.FEACONTROLSPanel);
            app.StatusLabel.Text = 'Ready';
            app.StatusLabel_2 = uilabel(app.FEACONTROLSPanel);
            app.StatusLabel_2.Text = 'Status';
            app.USERINPUTSPanel = uipanel(app.UIFigure);
            app.USERINPUTSPanel.Title = 'USER INPUTS';
            app.ThrustForceEditFieldLabel = uilabel(app.USERINPUTSPanel);
            app.ThrustForceEditFieldLabel.Text = 'Thrust Force';
            app.ThrustForceEditField = uieditfield(app.USERINPUTSPanel, 'numeric');
            app.ThrustForceEditField.ValueChangedFcn = createCallbackFcn(app, @ThrustForceEditFieldValueChanged, true);
            app.CuttingForceEditFieldLabel = uilabel(app.USERINPUTSPanel);
            app.CuttingForceEditFieldLabel.Text = 'Cutting Force';
            app.CuttingForceEditField = uieditfield(app.USERINPUTSPanel, 'numeric');
            app.CuttingForceEditField.ValueChangedFcn = createCallbackFcn(app, @CuttingForceEditFieldValueChanged, true);
            app.ThicknessEditFieldLabel = uilabel(app.USERINPUTSPanel);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.USERINPUTSPanel, 'numeric');
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.YoungsModulusEditFieldLabel = uilabel(app.USERINPUTSPanel);
            app.YoungsModulusEditFieldLabel.Text = 'Young''s Modulus';
            app.YoungsModulusEditField = uieditfield(app.USERINPUTSPanel, 'numeric');
            app.YoungsModulusEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusEditFieldValueChanged, true);
            app.PoissonsRatioEditFieldLabel = uilabel(app.USERINPUTSPanel);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.USERINPUTSPanel, 'numeric');
            app.PoissonsRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonsRatioEditFieldValueChanged, true);
            app.ResultTypeDropDownLabel = uilabel(app.USERINPUTSPanel);
            app.ResultTypeDropDownLabel.Text = 'Result Type';
            app.ResultDropDown = uidropdown(app.USERINPUTSPanel);
            app.ResultDropDown.Items = {'Deformation', 'Sx Stress', 'Sy Stress', 'Sxy Stress'};
            app.ResultDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultDropDownValueChanged2, true);
            app.ResultDropDown.Value = 'Deformation';
            app.ResettoDefaultsButton = uibutton(app.USERINPUTSPanel, 'push');
            app.ResettoDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultsButtonPushed, true);
            app.ResettoDefaultsButton.Text = 'Reset to Defaults';
            app.MeshSliderLabel = uilabel(app.USERINPUTSPanel);
            app.MeshSliderLabel.Text = 'Mesh Slider';
            app.MeshSlider = uislider(app.USERINPUTSPanel);
            app.RESULTSPanel = uipanel(app.UIFigure);
            app.RESULTSPanel.Title = 'RESULTS';
            app.MaxLabel = uilabel(app.RESULTSPanel);
            app.MaxLabel.Text = 'Max';
            app.MinLabel = uilabel(app.RESULTSPanel);
            app.MinLabel.Text = 'Min';
            app.TypeLabel = uilabel(app.RESULTSPanel);
            app.TypeLabel.Text = 'Type';
            app.SxStressLabel = uilabel(app.RESULTSPanel);
            app.SxStressLabel.Text = 'Sx Stress';
            app.SyStressLabel = uilabel(app.RESULTSPanel);
            app.SyStressLabel.Text = 'Sy Stress';
            app.SxyStressLabel = uilabel(app.RESULTSPanel);
            app.SxyStressLabel.Text = 'Sxy Stress';
            app.ExStrainLabel = uilabel(app.RESULTSPanel);
            app.ExStrainLabel.Text = 'Ex Strain';
            app.EyStrainLabel = uilabel(app.RESULTSPanel);
            app.EyStrainLabel.Text = 'Ey Strain';
            app.GxyStrainLabel = uilabel(app.RESULTSPanel);
            app.GxyStrainLabel.Text = 'Gxy Strain';
            app.VonMisesLabel = uilabel(app.RESULTSPanel);
            app.VonMisesLabel.Text = 'Von Mises';
            app.SxMaxLabel = uilabel(app.RESULTSPanel);
            app.SxMaxLabel.Text = 'Sx Max';
            app.SxMinLabel = uilabel(app.RESULTSPanel);
            app.SxMinLabel.Text = 'Sx Min';
            app.SyMaxLabel = uilabel(app.RESULTSPanel);
            app.SyMaxLabel.Text = 'Sy Max';
            app.SyMinLabel = uilabel(app.RESULTSPanel);
            app.SyMinLabel.Text = 'Sy Min';
            app.SxyMaxLabel = uilabel(app.RESULTSPanel);
            app.SxyMaxLabel.Text = 'Sxy Max';
            app.SxyMinLabel = uilabel(app.RESULTSPanel);
            app.SxyMinLabel.Text = 'Sxy Min';
            app.ExMaxLabel = uilabel(app.RESULTSPanel);
            app.ExMaxLabel.Text = 'Ex Max';
            app.ExMinLabel = uilabel(app.RESULTSPanel);
            app.ExMinLabel.Text = 'Ex Min';
            app.EyMaxLabel = uilabel(app.RESULTSPanel);
            app.EyMaxLabel.Text = 'Ey Max';
            app.EyMinLabel = uilabel(app.RESULTSPanel);
            app.EyMinLabel.Text = 'Ey Min';
            app.GxyMaxLabel = uilabel(app.RESULTSPanel);
            app.GxyMaxLabel.Text = 'Gxy Max';
            app.GxyMinLabel = uilabel(app.RESULTSPanel);
            app.GxyMinLabel.Text = 'Gxy Min';
            app.VonMisesMaxLabel = uilabel(app.RESULTSPanel);
            app.VonMisesMaxLabel.Text = 'Von Mises Max';
            app.VonMisesMinLabel = uilabel(app.RESULTSPanel);
            app.VonMisesMinLabel.Text = 'Von Mises Min';
            app.USEROUTPUTSPanel = uipanel(app.UIFigure);
            app.USEROUTPUTSPanel.Title = 'USER OUTPUTS';
            app.RestoreSaveButton = uibutton(app.USEROUTPUTSPanel, 'push');
            app.RestoreSaveButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreSaveButtonPushed, true);
            app.RestoreSaveButton.Text = 'Restore Save';
            app.ExportResultsButton = uibutton(app.USEROUTPUTSPanel, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.SelectBoundaryConditionsButton = uibutton(app.USEROUTPUTSPanel, 'push');
            app.SelectBoundaryConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBoundaryConditionsButtonPushed, true);
            app.SelectBoundaryConditionsButton.Text = 'Select Boundary Conditions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIforFEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end